classdef tcav_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes2                     matlab.ui.control.UIAxes
        StartButton_2               matlab.ui.control.Button
        CloseAppButton              matlab.ui.control.Button
        SelectcameraDropDownLabel   matlab.ui.control.Label
        SelectCameraDropDown        matlab.ui.control.DropDown
        ExampleTCAVGUILabel         matlab.ui.control.Label
        ShowRawCheckBox             matlab.ui.control.CheckBox
        ShowBMCheckBox              matlab.ui.control.CheckBox
        CalibrateEnergyCheckBox     matlab.ui.control.CheckBox
        CalibrateTimeCheckBox       matlab.ui.control.CheckBox
        ResolutionbitsSpinnerLabel  matlab.ui.control.Label
        ResolutionbitsSpinner       matlab.ui.control.Spinner
        SelectBeamButton            matlab.ui.control.StateButton
        UIAxesTop                   matlab.ui.control.UIAxes
        UIAxesRight                 matlab.ui.control.UIAxes
        GlobalOriginLabel           matlab.ui.control.Label
        EditField11                 matlab.ui.control.EditField
        EditField64                 matlab.ui.control.EditField
        EditField22                 matlab.ui.control.EditField
        ROILabel_2                  matlab.ui.control.Label
        EditField21                 matlab.ui.control.EditField
        EditField54                 matlab.ui.control.EditField
        EditField44                 matlab.ui.control.EditField
        EditField34                 matlab.ui.control.EditField
        EditField63                 matlab.ui.control.EditField
        EditField53                 matlab.ui.control.EditField
        EditField43                 matlab.ui.control.EditField
        EditField12                 matlab.ui.control.EditField
        EditField33                 matlab.ui.control.EditField
        EditField62                 matlab.ui.control.EditField
        EditField52                 matlab.ui.control.EditField
        EditField13                 matlab.ui.control.EditField
        EditField23                 matlab.ui.control.EditField
        EditField42                 matlab.ui.control.EditField
        EditField32                 matlab.ui.control.EditField
        OriginLabel_2               matlab.ui.control.Label
        EditField61                 matlab.ui.control.EditField
        EditField51                 matlab.ui.control.EditField
        EditField41                 matlab.ui.control.EditField
        EditField31                 matlab.ui.control.EditField
        xLabel                      matlab.ui.control.Label
        yLabel                      matlab.ui.control.Label
        unitLabel                   matlab.ui.control.Label
        DefaulltBeamLabel           matlab.ui.control.Label
        ROISpanLabel                matlab.ui.control.Label
        EditField24                 matlab.ui.control.EditField
        EditField14                 matlab.ui.control.EditField
        CurrentBeamLabel            matlab.ui.control.Label
        isRawLabel_2                matlab.ui.control.Label
        SubtractBackgroundCheckBox  matlab.ui.control.CheckBox
        PVDisplay                   matlab.ui.control.EditField
        ReadDAQConfigButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        aobj
        aobj_daq
        isOn = 0
        PVs_dict
    end
    
    methods (Access = private)
        
        function UIFigureCloseRequest(app,event)
            app.aobj.closeApp();
            delete(app)
        end 
    end
    
    methods (Access = public)   
        
        function updateTopProfilePlot(app, data, color)
            plot(app.UIAxesTop, data, color);
            if app.aobj.showBMOn && app.aobj.pois.beam.x > 0 && ...
                    app.aobj.pois.beam.x <= abs(app.aobj.pois.raw.roiXNP)
                xline(app.UIAxesTop, app.aobj.pois.beam.x, 'y', 'LineWidth', 2);
            end
            
            % match axes
            xticks_main = str2double(app.UIAxes2.XTickLabel);
            xlabel_main = app.UIAxes2.XLabel.String;
            
            xlabel(app.UIAxesTop, xlabel_main);
            app.UIAxesTop.XTickLabel = arrayfun(@(x) sprintf('%.2f', x), xticks_main, 'UniformOutput', false);
            app.UIAxesTop.XAxis.TickValues = app.UIAxes2.XAxis.TickValues;
            drawnow            
        end
        function updateRightProfilePlot(app, data, color)
            plot(app.UIAxesRight, data, 1:numel(data), color);
            if app.aobj.showBMOn && app.aobj.pois.beam.y > 0 && ...
                    app.aobj.pois.beam.y <= abs(app.aobj.pois.raw.roiYNP)
                yline(app.UIAxesRight, app.aobj.pois.beam.y, 'y', 'LineWidth', 2);
            end
            
            % match axes
            yticks_main = str2double(app.UIAxes2.YTickLabel);
            ylabel_main = app.UIAxes2.YLabel.String;
                        
            ylabel(app.UIAxesRight, ylabel_main);
            app.UIAxesRight.YTickLabel = arrayfun(@(x) sprintf('%.2f', x), yticks_main, 'UniformOutput', false);
            app.UIAxesRight.YAxis.TickValues = app.UIAxes2.YAxis.TickValues;
            drawnow            
        end
        
        function updateCursor(app)
          % Get the current point relative to UIAxes2
          cp = app.UIAxesRight.CurrentPoint;   % 2x3 array
          x = cp(1,1);
          y = cp(1,2);

        end
        
        function SelectBeamActivate(app, event)
                % if there is a plot on axis
                hook = findobj(app.UIAxes2, 'Type', 'image');
                if ~isempty(hook)
                    if app.aobj.selectBeamOn
            
                        % Enable clicks
                        hook.ButtonDownFcn = @(src, event) app.imageClicked(src, event);
                        hook.PickableParts = 'all';
                        hook.HitTest = 'on';
                    else
                        % Disable clicks
                        hook.ButtonDownFcn = [];
                        hook.PickableParts = 'none';
                        hook.HitTest = 'off';
                    end
                end      
        end

        function imageClicked(app, src, event)
            ax = ancestor(src, 'axes');
            coords = ax.CurrentPoint(1,1:2); % [x y]
            x = coords(1);
            y = coords(2);

            % Just for debugging
            disp(['Clicked at X=' num2str(x) ', Y=' num2str(y)]);

            % Draw a red circle where you clicked
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, y, 'r+', 'MarkerSize', 5, 'LineWidth', 1.5);
            hold(app.UIAxes2, 'off');
            
            % get selected coord relative to default beam coords

            [app.aobj.pois.beam.relx,app.aobj.pois.beam.rely, app.aobj.pois.beam.isRaw] = deal(x-app.aobj.pois.defaultBeam.x, y-app.aobj.pois.defaultBeam.y, app.aobj.showRawOn);
                                
             %app.aobj.printPOIs();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %app.UIAxes2.XAxis.Visible = 'off';
            %app.UIAxes2.YAxis.Visible = 'off';
            axis(app.UIAxes2, 'image'); 
            axis(app.UIAxesTop, 'tight');
            axis(app.UIAxesRight, 'tight');
            app.UIAxesRight.YLabel.Rotation = 90;    
            set(app.UIAxesRight, 'YDir', 'reverse');
            
            %populate camera dropdown
            app.PVs_dict = PVConfig();
            PVs_dict_keys = keys(app.PVs_dict);
            app.SelectCameraDropDown.Items = PVs_dict_keys;
            app.SelectCameraDropDown.Value = PVs_dict_keys(1);
            app.PVDisplay.Value = app.PVs_dict(app.SelectCameraDropDown.Value);
            
            % sets app.aobj.cameraPV 
            app.aobj = tcav_gui_backend(app);
            app.aobj_daq = tcav_gui_daq_control(app);
            lh = addlistener(app.aobj_daq, 'daqPath', 'PostSet', @(src, event) app.aobj_daq.onDaqPathValueChanged(src, event));

        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            app.UIFigureCloseRequest()
        end

        % Button pushed function: StartButton_2
        function StartButtonUIAxes2Pushed(app, event)
            
            % switch on off state
            app.isOn = ~app.isOn;
            
            if app.aobj.plottingOnUIAxes2 == false
              app.aobj.plottingOnUIAxes2 = true;
              app.StartButton_2.Text = 'Stop';
              app.StartButton_2.BackgroundColor = [0.6353 0.0784 0.1843];
            else
              app.aobj.plottingOnUIAxes2 = false;
              app.StartButton_2.Text = 'Start';
              app.StartButton_2.BackgroundColor = [0.1333 0.349 0.0627];
            end

                        
            % enable select beam functionality
            app.SelectBeamButton.Enable = 'on';
        end

        % Value changed function: ShowBMCheckBox
        function ShowBMCheckBoxValueChanged(app, event)
            app.aobj.showBMOn = app.ShowBMCheckBox.Value;
        end

        % Value changed function: ShowRawCheckBox
        function ShowRawCheckBoxValueChanged(app, event)
            app.aobj.showRawOn = app.ShowRawCheckBox.Value;
        end

        % Value changed function: CalibrateEnergyCheckBox
        function CalibrateEnergyCheckBoxValueChanged(app, event)
            app.aobj.calEOn = app.CalibrateEnergyCheckBox.Value;
        end

        % Value changed function: CalibrateTimeCheckBox
        function CalibrateTimeCheckBoxValueChanged(app, event)
            app.aobj.calTOn = app.CalibrateTimeCheckBox.Value;
            
        end

        % Value changed function: ResolutionbitsSpinner
        function ResolutionbitsSpinnerValueChanged(app, event)
            app.aobj.bitDepth = app.ResolutionbitsSpinner.Value;

        end

        % Value changed function: SelectCameraDropDown
        function SelectCameraDropDownValueChanged(app, event)
            cameraName = app.SelectCameraDropDown.Value;
            app.aobj.cameraPV = app.PVs_dict(cameraName);
            app.PVDisplay.Value = app.aobj.cameraPV;
            app.aobj.newPlot = true;
            
            % disable beam selection
            app.SelectBeamButton.Value = false;
            app.aobj.selectBeamOn = false;
            app.SelectBeamActivate(event);
            
            % if i stop and change cams disable select beam button
            if app.SelectBeamButton.Enable && ~app.isOn
                app.SelectBeamButton.Enable = 'off';
            end
            
        end

        % Value changed function: SelectBeamButton
        function SelectBeamButtonValueChanged(app, event)
            app.aobj.selectBeamOn = app.SelectBeamButton.Value;
            
            % if i stop plotting i can still select on last frame
            % if new cam but not yet started, disable select on last frame
            if ~app.aobj.plottingOn && ~app.aobj.newPlot
                app.SelectBeamActivate(event);
            end
        end

        % Value changed function: SubtractBackgroundCheckBox
        function SubtractBackgroundCheckBoxValueChanged(app, event)
            app.aobj.subtractBGOn = app.SubtractBackgroundCheckBox.Value;
            
        end

        % Button pushed function: ReadDAQConfigButton
        function ReadDAQConfigButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select a MAT file');
            
            % checks on correctness of filename
            if isequal(file, 0)
                error('No file selected. Canceled.');
            else
                [~,~,ext] = fileparts(file);
                if ~strcmpi(ext, '.mat')   % case-insensitive comparison
                    error('The selected file does not have a .mat extension.');
                else
                    app.aobj_daq.daqPath = fullfile(path, file);
                end
            end
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'An image')
            xlabel(app.UIAxes2, 'X [mm]')
            ylabel(app.UIAxes2, 'Y [mm]')
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonUIAxes2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.CloseAppButton = uibutton(app.UIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.SelectcameraDropDownLabel = uilabel(app.UIFigure);
            app.SelectcameraDropDownLabel.Text = 'Select camera';
            app.SelectCameraDropDown = uidropdown(app.UIFigure);
            app.SelectCameraDropDown.Items = {};
            app.SelectCameraDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCameraDropDownValueChanged, true);
            app.SelectCameraDropDown.Value = {};
            app.ExampleTCAVGUILabel = uilabel(app.UIFigure);
            app.ExampleTCAVGUILabel.Text = 'Example TCAV GUI';
            app.ShowRawCheckBox = uicheckbox(app.UIFigure);
            app.ShowRawCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawCheckBoxValueChanged, true);
            app.ShowRawCheckBox.Text = 'Show Raw';
            app.ShowBMCheckBox = uicheckbox(app.UIFigure);
            app.ShowBMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowBMCheckBoxValueChanged, true);
            app.ShowBMCheckBox.Text = 'Show BM';
            app.CalibrateEnergyCheckBox = uicheckbox(app.UIFigure);
            app.CalibrateEnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalibrateEnergyCheckBoxValueChanged, true);
            app.CalibrateEnergyCheckBox.Text = 'Calibrate Energy';
            app.CalibrateTimeCheckBox = uicheckbox(app.UIFigure);
            app.CalibrateTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalibrateTimeCheckBoxValueChanged, true);
            app.CalibrateTimeCheckBox.Text = 'Calibrate Time';
            app.ResolutionbitsSpinnerLabel = uilabel(app.UIFigure);
            app.ResolutionbitsSpinnerLabel.Text = 'Resolution [bits]';
            app.ResolutionbitsSpinner = uispinner(app.UIFigure);
            app.ResolutionbitsSpinner.Limits = [0 16];
            app.ResolutionbitsSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionbitsSpinnerValueChanged, true);
            app.SelectBeamButton = uibutton(app.UIFigure, 'state');
            app.SelectBeamButton.ValueChangedFcn = createCallbackFcn(app, @SelectBeamButtonValueChanged, true);
            app.SelectBeamButton.Enable = 'off';
            app.SelectBeamButton.Text = 'Select Beam';
            app.UIAxesTop = uiaxes(app.UIFigure);
            title(app.UIAxesTop, 'Title')
            xlabel(app.UIAxesTop, 'X [mm]')
            ylabel(app.UIAxesTop, 'Signal [a.u.]')
            app.UIAxesRight = uiaxes(app.UIFigure);
            title(app.UIAxesRight, 'Title')
            xlabel(app.UIAxesRight, 'Signal [a.u.]')
            ylabel(app.UIAxesRight, 'Y [mm]')
            app.GlobalOriginLabel = uilabel(app.UIFigure);
            app.GlobalOriginLabel.Text = 'Global Origin';
            app.EditField11 = uieditfield(app.UIFigure, 'text');
            app.EditField11.Editable = 'off';
            app.EditField64 = uieditfield(app.UIFigure, 'text');
            app.EditField64.Editable = 'off';
            app.EditField22 = uieditfield(app.UIFigure, 'text');
            app.EditField22.Editable = 'off';
            app.ROILabel_2 = uilabel(app.UIFigure);
            app.ROILabel_2.Text = 'ROI';
            app.EditField21 = uieditfield(app.UIFigure, 'text');
            app.EditField21.Editable = 'off';
            app.EditField54 = uieditfield(app.UIFigure, 'text');
            app.EditField54.Editable = 'off';
            app.EditField44 = uieditfield(app.UIFigure, 'text');
            app.EditField44.Editable = 'off';
            app.EditField34 = uieditfield(app.UIFigure, 'text');
            app.EditField34.Editable = 'off';
            app.EditField63 = uieditfield(app.UIFigure, 'text');
            app.EditField63.Editable = 'off';
            app.EditField53 = uieditfield(app.UIFigure, 'text');
            app.EditField53.Editable = 'off';
            app.EditField43 = uieditfield(app.UIFigure, 'text');
            app.EditField43.Editable = 'off';
            app.EditField12 = uieditfield(app.UIFigure, 'text');
            app.EditField12.Editable = 'off';
            app.EditField33 = uieditfield(app.UIFigure, 'text');
            app.EditField33.Editable = 'off';
            app.EditField62 = uieditfield(app.UIFigure, 'text');
            app.EditField62.Editable = 'off';
            app.EditField52 = uieditfield(app.UIFigure, 'text');
            app.EditField52.Editable = 'off';
            app.EditField13 = uieditfield(app.UIFigure, 'text');
            app.EditField13.Editable = 'off';
            app.EditField23 = uieditfield(app.UIFigure, 'text');
            app.EditField23.Editable = 'off';
            app.EditField42 = uieditfield(app.UIFigure, 'text');
            app.EditField42.Editable = 'off';
            app.EditField32 = uieditfield(app.UIFigure, 'text');
            app.EditField32.Editable = 'off';
            app.OriginLabel_2 = uilabel(app.UIFigure);
            app.OriginLabel_2.Text = 'Origin';
            app.EditField61 = uieditfield(app.UIFigure, 'text');
            app.EditField61.Editable = 'off';
            app.EditField51 = uieditfield(app.UIFigure, 'text');
            app.EditField51.Editable = 'off';
            app.EditField41 = uieditfield(app.UIFigure, 'text');
            app.EditField41.Editable = 'off';
            app.EditField31 = uieditfield(app.UIFigure, 'text');
            app.EditField31.Editable = 'off';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.unitLabel = uilabel(app.UIFigure);
            app.unitLabel.Text = 'unit';
            app.DefaulltBeamLabel = uilabel(app.UIFigure);
            app.DefaulltBeamLabel.Text = 'Defaullt Beam';
            app.ROISpanLabel = uilabel(app.UIFigure);
            app.ROISpanLabel.Text = 'ROI Span';
            app.EditField24 = uieditfield(app.UIFigure, 'text');
            app.EditField24.Editable = 'off';
            app.EditField14 = uieditfield(app.UIFigure, 'text');
            app.EditField14.Editable = 'off';
            app.CurrentBeamLabel = uilabel(app.UIFigure);
            app.CurrentBeamLabel.Text = 'Current Beam';
            app.isRawLabel_2 = uilabel(app.UIFigure);
            app.isRawLabel_2.Text = 'isRaw';
            app.SubtractBackgroundCheckBox = uicheckbox(app.UIFigure);
            app.SubtractBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractBackgroundCheckBoxValueChanged, true);
            app.SubtractBackgroundCheckBox.Text = 'Subtract Background';
            app.PVDisplay = uieditfield(app.UIFigure, 'text');
            app.ReadDAQConfigButton = uibutton(app.UIFigure, 'push');
            app.ReadDAQConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDAQConfigButtonPushed, true);
            app.ReadDAQConfigButton.Text = 'Read DAQ Config';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tcav_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end